classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BestMatchEditField            matlab.ui.control.EditField
        BestMatchEditFieldLabel       matlab.ui.control.Label
        MatchedProfileEditField       matlab.ui.control.EditField
        MatchedProfileEditFieldLabel  matlab.ui.control.Label
        EuclideanDistanceEditField    matlab.ui.control.EditField
        EuclideanDistanceEditFieldLabel  matlab.ui.control.Label
        AddprofileButton              matlab.ui.control.Button
        AddanImagetotheProfileButton  matlab.ui.control.Button
        RecognizeButton               matlab.ui.control.Button
        AddImageButton                matlab.ui.control.Button
        UIAxesOriginalImage           matlab.ui.control.UIAxes
        UIAxesRecognizedImage         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            add_image();
        end

        % Button pushed function: RecognizeButton
        function RecognizeButtonPushed(app, event)
            [                           ...
                img_original,       ... % Original image, for face recognition output
                img_recognized,     ... % Image recognized by the alghoritm
                best_match,         ... % best matched image
                matched_profile,    ... % best matched profile
                euclidean_distance  ... % distance between matched image and the original one
            ] = face_recognition();

            % Plot the principal and interest
            imshow(img_original, 'parent', app.UIAxesOriginalImage);
            imshow(img_recognized, 'parent', app.UIAxesRecognizedImage);
            app.BestMatchEditField.Value = num2str(best_match);
            app.EuclideanDistanceEditField.Value = num2str(euclidean_distance);
            app.MatchedProfileEditField.Value = num2str(matched_profile);
        end

        % Button pushed function: AddanImagetotheProfileButton
        function AddanImagetotheProfileButtonPushed(app, event)
            add_image_to_the_profile();
        end

        % Button pushed function: AddprofileButton
        function AddprofileButtonPushed(app, event)
            add_profile();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesRecognizedImage = uiaxes(app.UIFigure);
            title(app.UIAxesRecognizedImage, 'Recognized Image')
            app.UIAxesOriginalImage = uiaxes(app.UIFigure);
            title(app.UIAxesOriginalImage, 'Original Image')
            app.AddImageButton = uibutton(app.UIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = 'Add Image';
            app.RecognizeButton = uibutton(app.UIFigure, 'push');
            app.RecognizeButton.ButtonPushedFcn = createCallbackFcn(app, @RecognizeButtonPushed, true);
            app.RecognizeButton.Text = 'Recognize';
            app.AddanImagetotheProfileButton = uibutton(app.UIFigure, 'push');
            app.AddanImagetotheProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddanImagetotheProfileButtonPushed, true);
            app.AddanImagetotheProfileButton.Text = 'Add an Image to the Profile';
            app.AddprofileButton = uibutton(app.UIFigure, 'push');
            app.AddprofileButton.ButtonPushedFcn = createCallbackFcn(app, @AddprofileButtonPushed, true);
            app.AddprofileButton.Text = 'Add profile';
            app.EuclideanDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.EuclideanDistanceEditFieldLabel.Text = 'Euclidean Distance';
            app.EuclideanDistanceEditField = uieditfield(app.UIFigure, 'text');
            app.MatchedProfileEditFieldLabel = uilabel(app.UIFigure);
            app.MatchedProfileEditFieldLabel.Text = 'Matched Profile';
            app.MatchedProfileEditField = uieditfield(app.UIFigure, 'text');
            app.BestMatchEditFieldLabel = uilabel(app.UIFigure);
            app.BestMatchEditFieldLabel.Text = 'Best Match';
            app.BestMatchEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end